classdef robot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        TextScaleEditField        matlab.ui.control.NumericEditField
        TextScaleEditFieldLabel   matlab.ui.control.Label
        ViewDropDown              matlab.ui.control.DropDown
        ViewDropDownLabel         matlab.ui.control.Label
        CoordinateScaleEditField  matlab.ui.control.NumericEditField
        CoordinateScaleLabel      matlab.ui.control.Label
        ShapeScaleEditField       matlab.ui.control.NumericEditField
        ShapeScaleLabel           matlab.ui.control.Label
        Joint7EditField           matlab.ui.control.NumericEditField
        Joint7EditFieldLabel      matlab.ui.control.Label
        Joint6EditField           matlab.ui.control.NumericEditField
        Joint6EditFieldLabel      matlab.ui.control.Label
        Joint5EditField           matlab.ui.control.NumericEditField
        Joint5EditFieldLabel      matlab.ui.control.Label
        Joint4EditField           matlab.ui.control.NumericEditField
        Joint4EditFieldLabel      matlab.ui.control.Label
        Joint3EditField           matlab.ui.control.NumericEditField
        Joint3EditFieldLabel      matlab.ui.control.Label
        Joint2EditField           matlab.ui.control.NumericEditField
        Joint2EditFieldLabel      matlab.ui.control.Label
        Joint1EditField           matlab.ui.control.NumericEditField
        Joint1EditFieldLabel      matlab.ui.control.Label
        Slider_7                  matlab.ui.control.Slider
        Slider_6                  matlab.ui.control.Slider
        Slider_5                  matlab.ui.control.Slider
        Slider_4                  matlab.ui.control.Slider
        Slider_3                  matlab.ui.control.Slider
        Slider                    matlab.ui.control.Slider
        Slider_2                  matlab.ui.control.Slider
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DH % will hold current DH values
        limit_table %will hold the limit values
        joint_type %will hold joint types. 1 for revolute,2 for prismatic
        num_joint %total number of joint
        scale_coordinate % scale factor for coordinate
        scale_shape % scale factor for shape (cylinder or prism)
        scale_text %scale factor of the text
        frame % this will hold all the frames two for each joint (for shoulder offset)
        R % will hold transformation matrices. it is equivalent to T.
        colors %will hold list of colors for different joints

    end
    
    methods (Access = private)
        
        function results = initialize_ui(app)
            %get number of joints
            app.num_joint=size(app.DH,1);
            %find the positions of inf. results in row and column numbers .
            %row numbers ar unordered. We need orderd joint numbers
            [rows,columns]=find(app.DH==inf);
            %sort the rows incrementally to get sequential joint order. we
            %do not need the first value as it is already sorted. the
            %second value holds either 1 (revolute) or 2 (prismatic) 
            sorted_locations=sortrows([rows,columns]);
            app.joint_type=sorted_locations(:,2);
            %activate fields for existing joints
            if app.num_joint>0
                app.Joint1EditField.Enable='on';
                app.Joint1EditField.Limits=app.limit_table(1,:);
                app.Slider.Enable="on";
                app.Slider.Limits=app.limit_table(1,:);
                app.Slider.Value=app.limit_table(1,1); %set the value to lower limit
                app.Joint1EditField.Value=app.limit_table(1,1);
            end
            if app.num_joint>1
                app.Joint2EditField.Enable='on';
                app.Joint2EditField.Limits=app.limit_table(2,:);
                app.Slider_2.Enable="on";
                app.Slider_2.Limits=app.limit_table(2,:);
                app.Slider_2.Value=app.limit_table(2,1);
                app.Joint2EditField.Value=app.limit_table(2,1);
            end
            if app.num_joint>2
                app.Joint3EditField.Enable='on';
                app.Joint3EditField.Limits=app.limit_table(3,:);
                app.Slider_3.Enable="on";
                app.Slider_3.Limits=app.limit_table(3,:);
                app.Slider_3.Value=app.limit_table(3,1);
                app.Joint3EditField.Value=app.limit_table(3,1);
            end
            if app.num_joint>3
                app.Joint4EditField.Enable='on';
                app.Joint4EditField.Limits=app.limit_table(4,:);
                app.Slider_4.Enable="on";
                app.Slider_4.Limits=app.limit_table(4,:);
                app.Slider_4.Value=app.limit_table(4,1);
                app.Joint4EditField.Value=app.limit_table(4,1);
            end
            if app.num_joint>4
                app.Joint5EditField.Enable='on';
                app.Joint5EditField.Limits=app.limit_table(5,:);
                app.Slider_5.Enable="on";
                app.Slider_5.Limits=app.limit_table(5,:);
                app.Slider_5.Value=app.limit_table(5,1);
                app.Joint5EditField.Value=app.limit_table(5,1);
            end
            if app.num_joint>5
                app.Joint6EditField.Enable='on';
                app.Joint6EditField.Limits=app.limit_table(6,:);
                app.Slider_6.Enable="on";
                app.Slider_6.Limits=app.limit_table(6,:);
                app.Slider_6.Value=app.limit_table(6,1);
                app.Joint6EditField.Value=app.limit_table(6,1);
            end
            if app.num_joint>6
                app.Joint7EditField.Enable='on';
                app.Joint7EditField.Limits=app.limit_table(7,:);
                app.Slider_7.Enable="on";
                app.Slider_7.Limits=app.limit_table(7,:);
                app.Slider_7.Value=app.limit_table(7,1);
                app.Joint7EditField.Value=app.limit_table(7,1);
            end
            %initialize DH to lowest limit
            for i=1:app.num_joint
                app.DH(i,app.joint_type(i))=app.limit_table(i,1); 
            end
            %set the view
            view(app.UIAxes,[45 45 30]);
            grid(app.UIAxes,'on');
            axis(app.UIAxes,'equal');
%             set(app.UIAxes,'color',[0.85 0.85 0.85])
            
        end
        
        %This function calcultes transformation matrices given inputs as DH
        %table
        function results = calculate_transformations(app)
            for i=1:app.num_joint
                    %z operations
                    A(2*i-1,:,:)=Rot('Z',app.DH(i,1)*(pi/180))*Trans('Z',app.DH(i,2));
                
                    %X operations
                    A(2*i,:,:)=Trans('X',app.DH(i,3))*Rot('X',app.DH(i,4)*(pi/180));


            end
            for i=2:(app.num_joint*2)+1

                %calculating transformation matrices for all joints. This
                %is the effectively T matrices for each joints. R can seem misleading.
                %the first value of R is set to eye(4). The reshaping is
                %necessary because the way matlab saves data, it has to be
                %reshaped to proper matrix form before any matrix operation
                app.R(i,:,:)=reshape(app.R(i-1,:,:),[4 4])*reshape(A(i-1,:,:),[4 4]);

            end            
            
        end
        
        function results = draw_frames(app,pts,frame_num,col,k)
             hold(app.UIAxes,'on')
             
             %draw three axes
             quiver3(app.UIAxes,pts(1,1),pts(2,1),pts(3,1),pts(1,2)-pts(1,1),pts(2,2)-pts(2,1),pts(3,2)-pts(3,1),'color',col,"LineWidth",2,'MaxHeadSize',2,'AutoScale','off')
             quiver3(app.UIAxes,pts(1,1),pts(2,1),pts(3,1),pts(1,3)-pts(1,1),pts(2,3)-pts(2,1),pts(3,3)-pts(3,1),'color',col,"LineWidth",2,'MaxHeadSize',2,'AutoScale','off')
             quiver3(app.UIAxes,pts(1,1),pts(2,1),pts(3,1),pts(1,4)-pts(1,1),pts(2,4)-pts(2,1),pts(3,4)-pts(3,1),'color',col,"LineWidth",2,'MaxHeadSize',2,'AutoScale','off')
             

             %label the axes
             axis_o="\bf0_"+frame_num;
             axis_x="\bfx_"+frame_num;
             axis_y="\bfy_"+frame_num;
             axis_z="\bfz_"+frame_num;

             text(app.UIAxes,pts(1,1),pts(2,1),pts(3,1),axis_o,'Color',col,'FontSize',app.scale_text,'LineWidth',k)
             text(app.UIAxes,pts(1,2),pts(2,2),pts(3,2),axis_x,'Color',col,'FontSize',app.scale_text,'LineWidth',k)
             text(app.UIAxes,pts(1,3),pts(2,3),pts(3,3),axis_y,'Color',col,'FontSize',app.scale_text,'LineWidth',k)
             text(app.UIAxes,pts(1,4),pts(2,4),pts(3,4),axis_z,'Color',col,'FontSize',app.scale_text,'LineWidth',k)

        end
        %this function takes type of joint, transformation for that joint
        % R and scale of the mesh as inputs.
        function results = draw_mesh(app,type,R,k)
            hold(app.UIAxes,'on')
            if type==1
                data = stlread('cylinder.stl');
            elseif type==2
                data = stlread('prism.stl');
            else 
                eror('oops! Dont do that!')
            end
            %extract points from the stl
            points = [data.Points(:,1)'; data.Points(:,2)'; data.Points(:,3)';ones(1,size(data.Points,1))];
            %scale matrix
            scale=[k 0 0 0;0 k 0 0; 0 0 k 0;0 0 0 1];
            %transform and scale the points of the mesh
            points=R*scale*points;
            %extract required points
            points=points(1:3,:);
            %dump the new points into a new mesh variable
            ndata.Points=[points(1,:)' points(2,:)' points(3,:)'];
            %copy the existing mesh connectivity list to new one
            ndata.ConnectivityList=data.ConnectivityList;
            %next two lines are required to draw mesh from above points and
            %connectivity list
            TO = triangulation(ndata.ConnectivityList,ndata.Points(:,1),ndata.Points(:,2),ndata.Points(:,3));
            trimesh(TO,'FaceColor','r','EdgeColor',[0.6350 0.0780 0.1840],'FaceAlpha',.3,'Parent', app.UIAxes)           
        end
        
        function results = create_scene(app)
            hold(app.UIAxes,"off");
            cla(app.UIAxes);
            %start with initial 0th coordinate frame
            app.frame(1,:,:) =[0 1 0 0 
                               0 0 1 0  
                               0 0 0 1 
                               1 1 1 1 ];
            k=app.scale_coordinate;
            %create the scale matrix
            scale_matrix= [k 0 0 0
                           0 k 0 0
                           0 0 k 0
                           0 0 0 1];
            %scale the base 0th frame coordinate frame
            app.frame(1,:,:)=scale_matrix*reshape(app.frame(1,:,:),[4 4]);
            %reshape color matrix
            app.colors=reshape(app.colors,[3,8]);
            %calculate frames after each Z and X transforms.This will help
            %with shoulder offset
            for i=1:1:app.num_joint*2+1
                app.frame(i,:,:)=reshape(app.R(i,:,:),[4 4])*reshape(app.frame(1,:,:),[4 4]);
            end
            %only fraw odd frames as those are actual joints. evens are
            %important for shoulder offsets
            for i=1:app.num_joint
                draw_frames(app,reshape(app.frame(2*i-1,:,:),[4 4]),i-1,app.colors(:,i),app.scale_coordinate);
                draw_mesh(app,app.joint_type(i),reshape(app.R(2*i-1,:,:),[4 4]),app.scale_shape);
                
            end
            %draw the EE coordinate
            draw_frames(app,reshape(app.frame(app.num_joint*2+1,:,:),[4 4]),i,app.colors(:,i+1),app.scale_coordinate);
            draw_links(app,app.scale_shape)
            %set limits to auto to get a sense of overall entend
            xlim(app.UIAxes,'auto');
            ylim(app.UIAxes,'auto');
            %calculate limits to keep the robot in center without cropping
            %anything
            min_axis=min([(min((xlim(app.UIAxes)))),(min((ylim(app.UIAxes)))),min(((abs(zlim(app.UIAxes)))))]);
            max_axis=max([(max((xlim(app.UIAxes)))),(max((ylim(app.UIAxes)))),max(((abs(zlim(app.UIAxes)))))]);
            lim=max(abs(min_axis),abs(max_axis));
            %apply the new calculated limits
            xlim(app.UIAxes,[-1*lim,lim]);
            ylim(app.UIAxes,[-1*lim,lim]);


        end
        

        %this function will draw links connecting origin of each frame.
        % Excluding the last two frames. those two are for EE 
        function results = draw_links(app,k)
            app.colors=reshape(app.colors,[3,8]);
            col_i=1;
            for i=1:app.num_joint*2-2
                if mod(i,2)==1
                    col_i=(i+1)/2; %required so that shoulder offset also has the same color
                end
                %first point
                pts1=reshape(app.frame(i,:,:),[4,4]);
                %second point
                pts2=reshape(app.frame(i+1,:,:),[4,4]);
                %drawing the lines
                quiver3(app.UIAxes,pts2(1,1),pts2(2,1),pts2(3,1),pts1(1,1)-pts2(1,1),pts1(2,1)-pts2(2,1),pts1(3,1)-pts2(3,1),'color',app.colors(:,col_i),"LineWidth",2,'MaxHeadSize',k,'AutoScale','off','ShowArrowHead','off')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DH, joint_limits)
            %initializing all the variables
            app.DH=DH;
            app.limit_table=joint_limits;
            app.R(1,:,:)=eye(4);
            %list of colors to apply in different links
            app.colors=[[0 0.4470 0.7410];[0.4940 0.1840 0.5560];[0.8500 0.3250 0.0980];[0.9290 0.6940 0.1250];[0.4660 0.6740 0.1880];[1 0 1];[0.3010 0.7450 0.9330];[0.6350 0.0780 0.1840]];
            initialize_ui(app);

            max_link_length=max(max(app.DH(:,2:3)));
            if max_link_length>0
                app.scale_coordinate=max_link_length/4; %getting a sense of optimum factors
                app.scale_shape=max_link_length/7;
                app.scale_text=15;
                app.CoordinateScaleEditField.Value=app.scale_coordinate;
                app.ShapeScaleEditField.Value=app.scale_shape;
            else
                app.scale_coordinate=1; %getting a sense of optimum factors
                app.scale_shape=1;
                app.scale_text=15;
                app.CoordinateScaleEditField.Value=app.scale_coordinate;
                app.ShapeScaleEditField.Value=app.scale_shape;
            end
            %calculate transformations
            calculate_transformations(app);
            %create the scene! that means draw everything!
            create_scene(app);

            %get a grasp of everything without cropping anything
            xlim(app.UIAxes,'auto');
            ylim(app.UIAxes,'auto');
            %calculate limits to place the robot in center
            min_axis=min([(min((xlim(app.UIAxes)))),(min((ylim(app.UIAxes)))),min(((abs(zlim(app.UIAxes)))))]);
            max_axis=max([(max((xlim(app.UIAxes)))),(max((ylim(app.UIAxes)))),max(((abs(zlim(app.UIAxes)))))]);
            lim=max(abs(min_axis),abs(max_axis));
            %apply the limits
            xlim(app.UIAxes,[-1*lim,lim]);
            ylim(app.UIAxes,[-1*lim,lim]);


        end

        % Callback function
        function TESTButtonPushed(app, event)


        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            joint_id=1;
            app.Joint1EditField.Value=value;
            app.DH(joint_id,app.joint_type(joint_id))=value;
            calculate_transformations(app);
            create_scene(app);
            
        end

        % Value changed function: CoordinateScaleEditField
        function CoordinateScaleEditFieldValueChanged(app, event)
            app.scale_coordinate = app.CoordinateScaleEditField.Value;
            create_scene(app);
        end

        % Value changed function: ShapeScaleEditField
        function ShapeScaleEditFieldValueChanged(app, event)
            app.scale_shape = app.ShapeScaleEditField.Value;
            create_scene(app);
        end

        % Value changed function: Joint1EditField
        function Joint1EditFieldValueChanged(app, event)
            value = app.Joint1EditField.Value;
            joint_id=1;
            app.Slider.Value=value;
            app.DH(joint_id,app.joint_type(joint_id))=value;
            calculate_transformations(app);
            create_scene(app);
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            joint_id=2;
            app.Joint2EditField.Value=value;
            app.DH(joint_id,app.joint_type(joint_id))=value;
            calculate_transformations(app);
            create_scene(app);            
        end

        % Value changed function: Joint2EditField
        function Joint2EditFieldValueChanged(app, event)
            value = app.Joint2EditField.Value;
            joint_id=2;
            app.Slider_2.Value=value;
            app.DH(joint_id,app.joint_type(joint_id))=value;
            calculate_transformations(app);
            create_scene(app);     
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            value = app.Slider_3.Value;
            joint_id=3;
            app.Joint3EditField.Value=value;
            app.DH(joint_id,app.joint_type(joint_id))=value;
            calculate_transformations(app);
            create_scene(app);                
        end

        % Value changed function: Joint3EditField
        function Joint3EditFieldValueChanged(app, event)
            value = app.Joint3EditField.Value;
            joint_id=3;
            app.Slider_3.Value=value;
            app.DH(joint_id,app.joint_type(joint_id))=value;
            calculate_transformations(app);
            create_scene(app);               
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            value = app.Slider_4.Value;
            joint_id=4;
            app.Joint4EditField.Value=value;
            app.DH(joint_id,app.joint_type(joint_id))=value;
            calculate_transformations(app);
            create_scene(app);               
        end

        % Value changed function: Joint4EditField
        function Joint4EditFieldValueChanged(app, event)
            value = app.Joint4EditField.Value;
            joint_id=4;
            app.Slider_4.Value=value;
            app.DH(joint_id,app.joint_type(joint_id))=value;
            calculate_transformations(app);
            create_scene(app);               
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            value = app.Slider_5.Value;
            joint_id=5;
            app.Joint5EditField.Value=value;
            app.DH(joint_id,app.joint_type(joint_id))=value;
            calculate_transformations(app);
            create_scene(app);             
        end

        % Value changed function: Joint5EditField
        function Joint5EditFieldValueChanged(app, event)
            value = app.Joint5EditField.Value;
            joint_id=5;
            app.Slider_5.Value=value;
            app.DH(joint_id,app.joint_type(joint_id))=value;
            calculate_transformations(app);
            create_scene(app);              
        end

        % Value changed function: Slider_6
        function Slider_6ValueChanged(app, event)
            value = app.Slider_6.Value;
            joint_id=6;
            app.Joint6EditField.Value=value;
            app.DH(joint_id,app.joint_type(joint_id))=value;
            calculate_transformations(app);
            create_scene(app);               
        end

        % Value changed function: Joint6EditField
        function Joint6EditFieldValueChanged(app, event)
            value = app.Joint6EditField.Value;
            joint_id=6;
            app.Slider_6.Value=value;
            app.DH(joint_id,app.joint_type(joint_id))=value;
            calculate_transformations(app);
            create_scene(app);              
        end

        % Value changed function: Slider_7
        function Slider_7ValueChanged(app, event)
            value = app.Slider_7.Value;
            joint_id=7;
            app.Joint7EditField.Value=value;
            app.DH(joint_id,app.joint_type(joint_id))=value;
            calculate_transformations(app);
            create_scene(app);                 
        end

        % Value changed function: Joint7EditField
        function Joint7EditFieldValueChanged(app, event)
            value = app.Joint7EditField.Value;
            joint_id=7;
            app.Slider_7.Value=value;
            app.DH(joint_id,app.joint_type(joint_id))=value;
            calculate_transformations(app);
            create_scene(app);               
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            app.ViewDropDown.Value='Custom';
        end

        % Callback function
        function ViewDropDownClicked(app, event)
            item = event.InteractionInformation.Item;


        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            value = app.ViewDropDown.Value;
            switch value
                case "Isometric"
                    app.UIAxes.View = [45 45];
                case "XY"
                    
                    app.UIAxes.View = [0 90];
                case "XZ"
                    
                    app.UIAxes.View = [0 0];
                case "YZ"
                    app.UIAxes.View = [90 0];
                otherwise
                    
             
            end
            xlim(app.UIAxes,'auto');
            ylim(app.UIAxes,'auto');
               
            min_axis=min([(min((xlim(app.UIAxes)))),(min((ylim(app.UIAxes)))),min(((abs(zlim(app.UIAxes)))))]);
            max_axis=max([(max((xlim(app.UIAxes)))),(max((ylim(app.UIAxes)))),max(((abs(zlim(app.UIAxes)))))]);
            lim=max(abs(min_axis),abs(max_axis));
            xlim(app.UIAxes,[-1*lim,lim]);
            ylim(app.UIAxes,[-1*lim,lim]);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;

        end

        % Callback function
        function TextScaleEditFieldValueChanged(app, event)

            app.scale_text = app.TextScaleEditField.Value;
            create_scene(app);
        end

        % Value changed function: TextScaleEditField
        function TextScaleEditFieldValueChanged2(app, event)
            app.scale_text = app.TextScaleEditField.Value;
            create_scene(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Stick Figure')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Slider_2 = uislider(app.GridLayout);
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.Enable = 'off';
            app.Slider = uislider(app.GridLayout);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Enable = 'off';
            app.Slider_3 = uislider(app.GridLayout);
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.Enable = 'off';
            app.Slider_4 = uislider(app.GridLayout);
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.Enable = 'off';
            app.Slider_5 = uislider(app.GridLayout);
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Slider_5.Enable = 'off';
            app.Slider_6 = uislider(app.GridLayout);
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @Slider_6ValueChanged, true);
            app.Slider_6.Enable = 'off';
            app.Slider_7 = uislider(app.GridLayout);
            app.Slider_7.ValueChangedFcn = createCallbackFcn(app, @Slider_7ValueChanged, true);
            app.Slider_7.Enable = 'off';
            app.Joint1EditFieldLabel = uilabel(app.GridLayout);
            app.Joint1EditFieldLabel.Text = 'Joint 1';
            app.Joint1EditField = uieditfield(app.GridLayout, 'numeric');
            app.Joint1EditField.ValueChangedFcn = createCallbackFcn(app, @Joint1EditFieldValueChanged, true);
            app.Joint1EditField.Enable = 'off';
            app.Joint2EditFieldLabel = uilabel(app.GridLayout);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.Joint2EditField = uieditfield(app.GridLayout, 'numeric');
            app.Joint2EditField.ValueChangedFcn = createCallbackFcn(app, @Joint2EditFieldValueChanged, true);
            app.Joint2EditField.Enable = 'off';
            app.Joint3EditFieldLabel = uilabel(app.GridLayout);
            app.Joint3EditFieldLabel.Text = 'Joint 3';
            app.Joint3EditField = uieditfield(app.GridLayout, 'numeric');
            app.Joint3EditField.ValueChangedFcn = createCallbackFcn(app, @Joint3EditFieldValueChanged, true);
            app.Joint3EditField.Enable = 'off';
            app.Joint4EditFieldLabel = uilabel(app.GridLayout);
            app.Joint4EditFieldLabel.Text = 'Joint 4';
            app.Joint4EditField = uieditfield(app.GridLayout, 'numeric');
            app.Joint4EditField.ValueChangedFcn = createCallbackFcn(app, @Joint4EditFieldValueChanged, true);
            app.Joint4EditField.Enable = 'off';
            app.Joint5EditFieldLabel = uilabel(app.GridLayout);
            app.Joint5EditFieldLabel.Text = 'Joint 5';
            app.Joint5EditField = uieditfield(app.GridLayout, 'numeric');
            app.Joint5EditField.ValueChangedFcn = createCallbackFcn(app, @Joint5EditFieldValueChanged, true);
            app.Joint5EditField.Enable = 'off';
            app.Joint6EditFieldLabel = uilabel(app.GridLayout);
            app.Joint6EditFieldLabel.Text = 'Joint 6';
            app.Joint6EditField = uieditfield(app.GridLayout, 'numeric');
            app.Joint6EditField.ValueChangedFcn = createCallbackFcn(app, @Joint6EditFieldValueChanged, true);
            app.Joint6EditField.Enable = 'off';
            app.Joint7EditFieldLabel = uilabel(app.GridLayout);
            app.Joint7EditFieldLabel.Text = 'Joint 7';
            app.Joint7EditField = uieditfield(app.GridLayout, 'numeric');
            app.Joint7EditField.ValueChangedFcn = createCallbackFcn(app, @Joint7EditFieldValueChanged, true);
            app.Joint7EditField.Enable = 'off';
            app.ShapeScaleLabel = uilabel(app.GridLayout);
            app.ShapeScaleLabel.Text = 'Shape Scale';
            app.ShapeScaleEditField = uieditfield(app.GridLayout, 'numeric');
            app.ShapeScaleEditField.Limits = [1e-15 Inf];
            app.ShapeScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ShapeScaleEditFieldValueChanged, true);
            app.ShapeScaleEditField.Value = 1;
            app.CoordinateScaleLabel = uilabel(app.GridLayout);
            app.CoordinateScaleLabel.Text = 'Co-ordinate Scale';
            app.CoordinateScaleEditField = uieditfield(app.GridLayout, 'numeric');
            app.CoordinateScaleEditField.Limits = [1e-15 Inf];
            app.CoordinateScaleEditField.ValueChangedFcn = createCallbackFcn(app, @CoordinateScaleEditFieldValueChanged, true);
            app.CoordinateScaleEditField.Value = 1;
            app.ViewDropDownLabel = uilabel(app.GridLayout);
            app.ViewDropDownLabel.Text = 'View';
            app.ViewDropDown = uidropdown(app.GridLayout);
            app.ViewDropDown.Items = {'Isometric', 'XY', 'XZ', 'Custom'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Value = 'Isometric';
            app.TextScaleEditFieldLabel = uilabel(app.GridLayout);
            app.TextScaleEditFieldLabel.Text = 'Text Scale';
            app.TextScaleEditField = uieditfield(app.GridLayout, 'numeric');
            app.TextScaleEditField.ValueChangedFcn = createCallbackFcn(app, @TextScaleEditFieldValueChanged2, true);
            app.TextScaleEditField.Value = 15;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end